classdef curvatureTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SeptalCurvatureToolUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LoadmanualpointsButton          matlab.ui.control.Button
        SavemanualpointsButton          matlab.ui.control.Button
        PointsforcirclefittingDropDown  matlab.ui.control.DropDown
        PointsforcirclefittingLabel     matlab.ui.control.Label
        BatchanalysisButton             matlab.ui.control.Button
        CurvatureareaDropDown           matlab.ui.control.DropDown
        CurvatureareaDropDownLabel      matlab.ui.control.Label
        ReferencepointsDropDown         matlab.ui.control.DropDown
        ReferencepointsDropDownLabel    matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        TimeStepSpinner                 matlab.ui.control.Spinner
        TimeStepSpinnerLabel            matlab.ui.control.Label
        LoadContourButton               matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        ResetmanualpointsButton         matlab.ui.control.Button
        messagetxt                      matlab.ui.control.TextArea
        ContourView                     matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        CurvatureAreaView               matlab.ui.control.UIAxes
        CurvatureRatioView              matlab.ui.control.UIAxes
        CurvatureView                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        CoordContours;% contour data (4D matrix (Time, N, x, y))
        initialCall = 1;
        PixelSize; % Description
        UserSpecifiedSeptalPoints; % Description
        UserSpecifiedFreeWallPoints; % Description
        CurvatureSeptum % Septal curvature values      (N,i) N: time steps, i: mode (1:auto,2:manual)
        CurvatureFreeWall; % Freewall curvature values (N,i) N: time steps, i: mode (1:auto,2:manual)
        CurvatureRatio; % Description
        CountPointsSeptal = 0; % Description
        CountPointsFreewall = 0; % Description
        Points; % Description
        RefPoints; % Description
        ArchPoints; % Description
        ApproxCircleCenter; % Computed center of the circle
        ApproxCircleCenter_rvcurve; % center of the circle estimated from RV contour. used by area_based_minus/plus
        SubPixRes;     % Resolution described in the cvi output files.
        CurvatureArea; % 1: plus, 2: minus
        SAXimg;        % short axis slices imported from DICOM files.
        SOPInstanceUID;% used for re-arranging temporal data. 
        PixelSpacing; % Pixel Spacing on DICOM data
        NormCurvatureArea; % Normalized Curvature Area
        RefPoints_midSepWall; % re-computed RefPoints 
        weightParam;     % used to determine weights for least square fit
        registrationDone = false; % flag for registration (Manual mode)
        pointToMove;     % to save the index to move for manual correction
        ptsdirname;      % output file name (individual analysis)
    end
    
    methods (Access = private)
        
        function contourReader_cvifile(app,filename,pathToFile)
            % File select and open
            if nargin < 2
                if ispc
                    [filename, pathToFile] = uigetfile('','Select your Circle output file (.cvi42wsx).','*.cvi42wsx');                   
                else
                    [filename, pathToFile] = uigetfile('*');
                end
            end
            fid = fopen(fullfile(pathToFile, filename));
            %fid = fopen('C:\Users\Takashi\Documents\tmp\contour1.cvi42wsx');
            app.messagetxt.Value = 'Loading the cvi file....';
            pause(0.05);
            
            ilinetotal    = 0;
            if_in_contour_part = 0;
            if_read = zeros(1,5);
            if_done = zeros(1,5);
            iline   = zeros(1,5);
            icount  = zeros(1,5) + 1;
            if_got_subpix  = 0;
            if_got_pixsize = 0;
            t_count = 0;

            % read lines
            while ~feof(fid)
                ilinetotal       = ilinetotal + 1;
                line = fgetl(fid);
                if ~contains(line,'!--end of') && any(cell2mat(cellfun(@(x) contains(line,x),app.SOPInstanceUID,'UniformOutput',false)))
                    if_in_contour_part = 1;
                    startpos = strfind(line,'Hash:key=');
                    endpos   = strfind(line,'Hash:count');
                    SOP_cvi = line(startpos+length('Hash:key=')+1:endpos-3);
                    timepoint = find(contains(app.SOPInstanceUID,SOP_cvi));
                    t_count = t_count + 1;
                end

                if if_in_contour_part
                    % search for end line
                    if contains(line,'!--end of') && contains(line,SOP_cvi)
                        if ~all(if_done)
                            warning(['Some contour data may be missing for time step #',num2str(timepoint,'%0d')]);
                        end
                        if_in_contour_part = 0;
                        if_done(1:end) = 0;
                        continue;
                    end

                    if if_got_subpix==0 && contains(line,'SubpixelResolution')
                        txtstr = strsplit(line,{'>','<'});
                        app.SubPixRes = str2double(txtstr{3}); % assume all images have the same subpixel resolution
                        if_got_subpix = 1;
                    end

                    if if_got_pixsize == 0 && contains(line,'PixelSize')
                        line = fgetl(fid);
                        txtstr = strsplit(line,{'>','<'});
                        app.PixelSize(1) = str2double(txtstr{3});

                        line = fgetl(fid);
                        txtstr = strsplit(line,{'>','<'});
                        app.PixelSize(2) = str2double(txtstr{3});

                        if_got_pixsize = 1;
                    end

                    % beginning statements
                    if contains(line,'key="sacardialRefPoint"')
                        if_read(1) = 1;
                        iline(1)   = 1;
                    end

                    if contains(line,'key="sacardialInferiorRefPoint"')
                        if_read(2) = 1;
                        iline(2)   = 1;
                    end

                    if contains(line,'key="saepicardialContour"')
                        if_read(3) = 1;
                        iline(3)   = 1;
                    end

                    if contains(line,'key="sarvendocardialContour"')
                        if_read(4) = 1;
                        iline(4)   = 1;
                    end

                    if contains(line,'key="saendocardialContour"')
                        if_read(5) = 1;
                        iline(5)   = 1;
                    end

                    % end statement
                    if contains(line,"end of sacardialRefPoint")
                        if_read(1) = 0;
                        if_done(1) = 1;
                        icount(1)  = 1;
                    end

                    if contains(line,"end of sacardialInferiorRefPoint")
                        if_read(2) = 0;
                        if_done(2) = 1;
                        icount(2)  = 1;
                    end

                    if contains(line,"end of saepicardialContour")
                        if_read(3) = 0;
                        if_done(3) = 1;
                        icount(3)  = 1;
                    end

                    if contains(line,"end of sarvendocardialContour")
                        if_read(4) = 0;
                        if_done(4) = 1;
                        icount(4)  = 1;
                    end

                    if contains(line,"end of saendocardialContour")
                        if_read(5) = 0;
                        if_done(5) = 1;
                        icount(5)  = 1;
                    end


                    % read lines
                    if if_read(1) == 1
                        if contains(line,'Point:x') % x coordinate
                            txtstr = strsplit(line,{'>','<'});
                            app.RefPoints(timepoint,1,1) = str2double(txtstr{3});
                        end
                        if contains(line,'Point:y') % x coordinate
                            txtstr = strsplit(line,{'>','<'});
                            app.RefPoints(timepoint,1,2) = str2double(txtstr{3});
                        end
                    end

                    if if_read(2) == 1
                        if contains(line,'Point:x') % x coordinate
                            txtstr = strsplit(line,{'>','<'});
                            app.RefPoints(timepoint,2,1) = str2double(txtstr{3});
                        end
                        if contains(line,'Point:y') % x coordinate
                            txtstr = strsplit(line,{'>','<'});
                            app.RefPoints(timepoint,2,2) = str2double(txtstr{3});
                        end
                    end

                    if if_read(3) == 1
                        if contains(line,'Point:x') % x coordinate
                            txtstr = strsplit(line,{'>','<'});
                            coord_contours(timepoint,1,icount(3),1) = str2double(txtstr{3});
                        end
                        if contains(line,'Point:y') % x coordinate
                            txtstr = strsplit(line,{'>','<'});
                            coord_contours(timepoint,1,icount(3),2) = str2double(txtstr{3});
                            icount(3) = icount(3) + 1;
                        end
                    end

                    if if_read(4) == 1
                        if contains(line,'Point:x') % x coordinate
                            txtstr = strsplit(line,{'>','<'});
                            coord_contours(timepoint,2,icount(4),1) = str2double(txtstr{3});
                        end
                        if contains(line,'Point:y') % x coordinate
                            txtstr = strsplit(line,{'>','<'});
                            coord_contours(timepoint,2,icount(4),2) = str2double(txtstr{3});
                            icount(4) = icount(4) + 1;
                        end
                    end

                    if if_read(5) == 1
                        if contains(line,'Point:x') % x coordinate
                            txtstr = strsplit(line,{'>','<'});
                            coord_contours(timepoint,3,icount(5),1) = str2double(txtstr{3});
                        end
                        if contains(line,'Point:y') % x coordinate
                            txtstr = strsplit(line,{'>','<'});
                            coord_contours(timepoint,3,icount(5),2) = str2double(txtstr{3});
                            icount(5) = icount(5) + 1;
                        end
                    end


                end
            end
            if length(app.SOPInstanceUID) ~= t_count
                warning(['The number of DICOM files [',num2str(length(app.SOPInstanceUID),'%0d'),'] is different from the number of contours [',num2str(t_count,'%0d'),'].']);
            end
            fclose(fid);

            % scaling
            coord_contours(:,:,:,1) = coord_contours(:,:,:,1)/app.SubPixRes*app.PixelSize(1);
            coord_contours(:,:,:,2) = coord_contours(:,:,:,2)/app.SubPixRes*app.PixelSize(2);
            app.CoordContours = coord_contours;

            app.RefPoints(:,:,1) = app.RefPoints(:,:,1)/app.SubPixRes*app.PixelSize(1);
            app.RefPoints(:,:,2) = app.RefPoints(:,:,2)/app.SubPixRes*app.PixelSize(2);

            app.messagetxt.Value = 'cvi file loaded.';
            pause(0.05);

        end
        
        function drawContours(app,currentTimeStep)
            colors{1} = [0,1,0];
            colors{2} = [1,0,0];
            colors{3} = [0,0,1];
            xmean = 0; ymean = 0;
            hold(app.ContourView, 'off');
            imshow(app.SAXimg{currentTimeStep},[0 512],'Parent',app.ContourView, ...
                'Xdata',[1 size(app.SAXimg{currentTimeStep},2)*app.PixelSpacing(1)], ...
                'Ydata',[1 size(app.SAXimg{currentTimeStep},1)*app.PixelSpacing(2)]);
            hold(app.ContourView, 'on');
            % loop for contours
            for i = 1 : size(app.CoordContours,2)
                tmp = squeeze(squeeze(app.CoordContours(currentTimeStep,i,:,:)));
                tmp(tmp==0) = [];
                tmp = reshape(tmp,[],2);
                plot(app.ContourView, tmp(:,1),tmp(:,2),'Color',colors{i});
%                hold(app.ContourView, 'on');
                xmean = xmean + mean(tmp(:,1))/size(app.CoordContours,2);
                ymean = ymean + mean(tmp(:,2))/size(app.CoordContours,2);
            end
            % add reference points
            plot(app.ContourView,app.RefPoints_midSepWall(currentTimeStep,1,1),app.RefPoints_midSepWall(currentTimeStep,1,2),'ko');
            plot(app.ContourView,app.RefPoints_midSepWall(currentTimeStep,2,1),app.RefPoints_midSepWall(currentTimeStep,2,2),'ko');

            if app.initialCall == 1
                xlim(app.ContourView, [xmean-75*app.PixelSize(1) xmean+75*app.PixelSize(1)]);
                ylim(app.ContourView, [ymean-75*app.PixelSize(2) ymean+75*app.PixelSize(2)]);
                app.initialCall = 0;
            end
            
            if ~isempty(app.ArchPoints)
                ang=0:0.01:2*pi;
                if ~isempty(app.ArchPoints{currentTimeStep,2}) % septal curvature
                    plot(app.ContourView,app.ArchPoints{currentTimeStep,2}(:,1),app.ArchPoints{currentTimeStep,2}(:,2),'r-','LineWidth',2);
                    xp=1/app.CurvatureSeptum(currentTimeStep,1)*cos(ang);
                    yp=1/app.CurvatureSeptum(currentTimeStep,1)*sin(ang);
                    plot(app.ContourView,app.ApproxCircleCenter(currentTimeStep,2,1)+xp,app.ApproxCircleCenter(currentTimeStep,2,2)+yp,'r--');
                end
                if ~isempty(app.ArchPoints{currentTimeStep,1}) % Free wall curvature
                    plot(app.ContourView,app.ArchPoints{currentTimeStep,1}(:,1),app.ArchPoints{currentTimeStep,1}(:,2),'g-','LineWidth',2);
                    xp=1/app.CurvatureFreeWall(currentTimeStep,1)*cos(ang);
                    yp=1/app.CurvatureFreeWall(currentTimeStep,1)*sin(ang);
                    plot(app.ContourView,app.ApproxCircleCenter(currentTimeStep,1,1)+xp,app.ApproxCircleCenter(currentTimeStep,1,2)+yp,'g--');
                end
            end
            hold(app.ContourView, 'off');
        end
        
        function [ cx, cy, rinv ] = CircleFitting(app,x,y)
            x_sum   = sum(x);
            y_sum   = sum(y);
            xsq_sum = sum(x.^2);
            ysq_sum = sum(y.^2);
            xy_sum  = sum(x.*y);
            c       = length(x);

            LHS = [xsq_sum  xy_sum   x_sum;
                   xy_sum   ysq_sum  y_sum;
                   x_sum    y_sum    c     ];

            RHS = [-sum(x.^3    +  x.*y.^2);
                   -sum(x.^2.*y +  y.^3   );
                   -sum(x.^2    +  y.^2   )];

            X = LHS\RHS;

            cx   = -X(1)/2;
            cy   = -X(2)/2;
            r    = sqrt(cx^2+cy^2-X(3));
            rinv = 1/r;

        end
        
        function updateCurvaturePlot(app,SeptalCurvature,FreewallCurvature)
            % obtain class variables
            ntemp        = app.TimeStepSpinner.Limits;
            if strcmp(app.CurvatureareaDropDown.Value,'Line-based')
                areaApproach = 1;
            else
                areaApproach = 2;
            end
            % curvatures
            % plot
            plot(app.CurvatureView,ntemp(1):1:ntemp(2),SeptalCurvature,'ro');
            hold(app.CurvatureView,'on');
            y = spline(ntemp(1):1:ntemp(2),SeptalCurvature,ntemp(1):0.1:ntemp(2));
            pl_sc = plot(app.CurvatureView,ntemp(1):0.1:ntemp(2),y,'r-');

            plot(app.CurvatureView,ntemp(1):1:ntemp(2),FreewallCurvature,'go');
            y = spline(ntemp(1):1:ntemp(2),FreewallCurvature,ntemp(1):0.1:ntemp(2));
            pl_fwc = plot(app.CurvatureView,ntemp(1):0.1:ntemp(2),y,'g-');

            app.CurvatureRatio = SeptalCurvature./FreewallCurvature;
            app.CurvatureRatio(isnan(app.CurvatureRatio)) = 0;

            plot(app.CurvatureRatioView,ntemp(1):1:ntemp(2),app.CurvatureRatio,'ko');
            hold(app.CurvatureRatioView,'on');
            y = spline(ntemp(1):1:ntemp(2),app.CurvatureRatio,ntemp(1):0.1:ntemp(2));
            plot(app.CurvatureRatioView,ntemp(1):0.1:ntemp(2),y,'k-');
            
            % current time
            xline(app.CurvatureView,app.TimeStepSpinner.Value);
            yline(app.CurvatureView,0);
            xline(app.CurvatureRatioView,app.TimeStepSpinner.Value);
            yline(app.CurvatureRatioView,0);
            
            % figure legends
            legend(app.CurvatureView,[pl_sc,pl_fwc],{'Septal','Free wall'},'Location','southwest');
            legend(app.CurvatureView,"boxoff");
            hold(app.CurvatureView,'off');
            hold(app.CurvatureRatioView,'off');
            
            if strcmpi(app.PointsforcirclefittingDropDown.Value,'Auto') % draw curvature area only for auto mode
                % curvatur area
                % plot
                plot(app.CurvatureAreaView,ntemp(1):1:ntemp(2),app.CurvatureArea(:,areaApproach,1),'mo');
                hold(app.CurvatureAreaView,'on');
                y = spline(ntemp(1):1:ntemp(2),app.CurvatureArea(:,areaApproach,1),ntemp(1):0.1:ntemp(2));
                pl_ca_pl = plot(app.CurvatureAreaView,ntemp(1):0.1:ntemp(2),y,'m-');

                plot(app.CurvatureAreaView,ntemp(1):1:ntemp(2),app.CurvatureArea(:,areaApproach,2),'co');
                y = spline(ntemp(1):1:ntemp(2),app.CurvatureArea(:,areaApproach,2),ntemp(1):0.1:ntemp(2));
                pl_ca_mi = plot(app.CurvatureAreaView,ntemp(1):0.1:ntemp(2),y,'c-');

                % current time
                xline(app.CurvatureAreaView,app.TimeStepSpinner.Value);
                yline(app.CurvatureAreaView,0);

                % figure legends
                legend(app.CurvatureAreaView,[pl_ca_pl,pl_ca_mi],{'convex to RV','Convex to LV'},'Location','southwest');
                legend(app.CurvatureAreaView,"boxoff");
                hold(app.CurvatureAreaView,'off');
            end
        end
        
        function rinv = ComputeCurvature(app,points, i, j)
            hold(app.ContourView,'off');

            currentTimeStep = app.TimeStepSpinner.Value;
            tmp1  = squeeze(squeeze(app.CoordContours(currentTimeStep,i,:,:))); % use RV contour
            tmp2  = squeeze(squeeze(app.CoordContours(currentTimeStep,j,:,:))); % use RV contour
            LVend = squeeze(squeeze(app.CoordContours(currentTimeStep,3,:,:)));
            tmp1(tmp1==0) = [];
            tmp1 = reshape(tmp1,[],2);            
            tmp2(tmp2==0) = [];
            tmp2 = reshape(tmp2,[],2);            
            LVend(LVend==0) = [];
            LVend = reshape(LVend,[],2);            
            [~,minind1] = min(sqrt((tmp1(:,1)-points{1}(1,1)).^2+(tmp1(:,2)-points{1}(1,2)).^2));
            [~,minind2] = min(sqrt((tmp1(:,1)-points{2}(1,1)).^2+(tmp1(:,2)-points{2}(1,2)).^2));
            t1cen       = mean(tmp1,1);
            t2cen       = mean(tmp2,1);
            if minind1 < minind2
                t1arch1 = tmp1(minind1:minind2,:);
                t1arch2 = [tmp1(minind2:end,:);tmp1(1:minind1,:)];
            else
                t1arch1 = tmp1(minind2:minind1,:);
                t1arch2 = [tmp1(minind1:end,:);tmp1(1:minind2,:)];                
            end
            t1arch1cen  = mean(t1arch1,1);
            t1arch2cen  = mean(t1arch2,1);
            if i == 2 % septal curvature
                if norm(t2cen-t1arch1cen) < norm(t2cen-t1arch2cen)
                    t1arch = t1arch1;
                else
                    t1arch = t1arch2;
                end

                % if septal curvature, use mid points between RVendo and LVendo for p3
                [~,ind_1  ] = min(sqrt((LVend(:,1)-t1arch(1,1)).^2+(LVend(:,2)-t1arch(1,2)).^2));
                %[~,ind_mid] = min(sqrt((LVend(:,1)-t1arch(floor(end/2),1)).^2+(LVend(:,2)-t1arch(floor(end/2),2)).^2));
                [~,ind_end] = min(sqrt((LVend(:,1)-t1arch(end,1)).^2+(LVend(:,2)-t1arch(end,2)).^2));
                if ind_1 < ind_end
                    LVendoCurveSeg1 = LVend(ind_1:ind_end,:);
                    LVendoCurveSeg2 = [LVend(ind_end:end,:);LVend(1:ind_1,:)];
                else
                    LVendoCurveSeg1 = LVend(ind_end:ind_1,:);
                    LVendoCurveSeg2 = [LVend(ind_1:end,:);LVend(1:ind_end,:)];
                end
                if norm(t1cen-mean(LVendoCurveSeg1,1)) < norm(t1cen-mean(LVendoCurveSeg2,1))
                    LVendoSeptalSeg = LVendoCurveSeg1;
                else
                    LVendoSeptalSeg = LVendoCurveSeg2;
                end
                % smooth (optional)
                %LVendoSeptalSeg_org = LVendoSeptalSeg;
                %t1arch          = smoothdata(smoothdata(t1arch,1,"gaussian"),1,"gaussian");
                %LVendoSeptalSeg = smoothdata(smoothdata(LVendoSeptalSeg,1,"gaussian"),1,"gaussian");
                % obtain the third point to use for least square fit. Select
                % the most distant point from the line (LVendoSeptalSeg(1),LVendoSeptalSeg(end))
                a = (LVendoSeptalSeg(1,2) - LVendoSeptalSeg(end,2))/(LVendoSeptalSeg(1,1) - LVendoSeptalSeg(end,1));
                c = t1cen(1,2) - a*t1cen(1,1); % get a line y = ax + c that passes RV center
                c_org = LVendoSeptalSeg(1,2) - a*LVendoSeptalSeg(1,1); % get a line y = ax + c that passes RV center
                lowlim = floor(size(LVendoSeptalSeg,1)*0.15);
                highlim = ceil(size(LVendoSeptalSeg,1)*0.85);
                if isinf(a) % vertical line
                    dist = abs(LVendoSeptalSeg(:,1) - t1cen(1,1));
                    dist_for_weight = abs(LVendoSeptalSeg(:,1) - LVendoSeptalSeg(1,1));
                    line_bw_refpts(1:101,1) = t1arch(1,1);
                    line_bw_refpts(:,2) =  t1arch(1,2):(t1arch(end,2) - t1arch(1,2))/100:t1arch(end,2);
                else
                    dist = abs(a*LVendoSeptalSeg(:,1)-LVendoSeptalSeg(:,2)+c)/sqrt(a^2+1);
                    dist_for_weight = abs(a*LVendoSeptalSeg(:,1)-LVendoSeptalSeg(:,2)+c_org)/sqrt(a^2+1);
                    line_bw_refpts(:,1) = LVendoSeptalSeg(1,1):(LVendoSeptalSeg(end,1) - LVendoSeptalSeg(1,1))/100:LVendoSeptalSeg(end,1);
                    line_bw_refpts(:,2) = line_bw_refpts(:,1)*a+c_org;
                end
                if currentTimeStep == 1
                    app.weightParam = max(dist_for_weight);
                end

                for iseg = 1 : size(LVendoSeptalSeg,1)
                    [~, id] = min(sqrt((LVendoSeptalSeg(iseg,1)-line_bw_refpts(:,1)).^2 + (LVendoSeptalSeg(iseg,2)-line_bw_refpts(:,2)).^2));
                    vec = LVendoSeptalSeg(iseg,:) - line_bw_refpts(id(1),:);
                    if dot(t2cen-t1cen,vec) > 0
                        sign_dist(iseg,1) = -1;
                    else
                        sign_dist(iseg,1) =  1;
                    end
                end

                window = zeros(size(LVendoSeptalSeg,1),1);
                window(lowlim:highlim) = 1;
                tf_concav = findConcaveShape(app,LVendoSeptalSeg,a,window,dist);
                if any(~tf_concav.*window)
                    mask = ~tf_concav.*window;
                    mask(mask==0) = Inf;
                    [~,ind_bowRV] = min(dist.*mask);
                    %weight_lsf_bowRV = dist_for_weight(ind_bowRV)/app.weightParam;
                    weight_lsf_bowRV = 1;
                else
                    ind_bowRV = [];
                end    
                if any(tf_concav.*window)
                    mask = tf_concav.*window;
                    [~,ind_bowLV] = max(dist.*mask);
                    weight_lsf_bowLV = 1 - sign_dist(ind_bowLV)*(dist_for_weight(ind_bowLV)/app.weightParam);
                    if weight_lsf_bowLV > 1
                        weight_lsf_bowLV = 1;
                        weight_lsf_bowRV = 0;
                    else  
                        weight_lsf_bowRV = 1-weight_lsf_bowLV;
                    end
                else
                    ind_bowLV = [];
                %    ind_mid = floor(size(LVendoSeptalSeg,1)/2);
                end
                %if ind_mid/size(LVendoSeptalSeg,1)<0.3 || ind_mid/size(LVendoSeptalSeg,1)>0.7
                %    ind_mid = floor(size(LVendoSeptalSeg,1)/2);
                %end

                % find the corresponding RV endo coordinates for weighted average
                weight = 0.6;
                if ~isempty(ind_bowLV)
                    [~,ind_mid_arch] = min(sqrt((t1arch(:,1)-LVendoSeptalSeg(ind_bowLV,1)).^2+(t1arch(:,2)-LVendoSeptalSeg(ind_bowLV,2)).^2));
                    p2 = weight*LVendoSeptalSeg(ind_bowLV,:) + (1-weight)*t1arch(ind_mid_arch,:);
                end
                if ~isempty(ind_bowRV)
                    [~,ind_mid_arch2] = min(sqrt((t1arch(:,1)-LVendoSeptalSeg(ind_bowRV,1)).^2+(t1arch(:,2)-LVendoSeptalSeg(ind_bowRV,2)).^2));
                    p2_2 = weight*LVendoSeptalSeg(ind_bowRV,:) + (1-weight)*t1arch(ind_mid_arch2,:);
                end
                % obtain an (weighted) setpal midline
                if (ind_1 < ind_end && norm(t1cen-mean(LVendoCurveSeg1,1)) < norm(t1cen-mean(LVendoCurveSeg2,1))) || (ind_1 > ind_end && norm(t1cen-mean(LVendoCurveSeg1,1)) > norm(t1cen-mean(LVendoCurveSeg2,1)))  
                    p1 = weight*LVendoSeptalSeg(1      ,:) + (1-weight)*t1arch(1           ,:);
                    %p2 = weight*LVendoSeptalSeg(ind_mid,:) + (1-weight)*t1arch(ind_mid_arch,:);
                    p3 = weight*LVendoSeptalSeg(end    ,:) + (1-weight)*t1arch(end         ,:);
                else
                    p1 = weight*LVendoSeptalSeg(end    ,:) + (1-weight)*t1arch(1           ,:);
                    %p2 = weight*LVendoSeptalSeg(ind_mid,:) + (1-weight)*t1arch(ind_mid_arch,:);
                    p3 = weight*LVendoSeptalSeg(1      ,:) + (1-weight)*t1arch(end         ,:);
                end                    
                % save ref points
                app.RefPoints_midSepWall(currentTimeStep,1,:) = p1;
                app.RefPoints_midSepWall(currentTimeStep,2,:) = p3;
                % 3 point approximation
                if ~isempty(ind_bowRV) && ~isempty(ind_bowLV)
                    weight_lsf = [1,weight_lsf_bowLV,weight_lsf_bowRV,1];
                    %[ cx, cy, rinv ] = CircleFitting(app,[p1(1);p2(1);p2_2(1);p3(1)],[p1(2);p2(2);p2_2(2);p3(2)]);
                    [ cx, cy, rinv ] = CircleFitting_weighted(app,[p1(1);p2(1);p2_2(1);p3(1)],[p1(2);p2(2);p2_2(2);p3(2)],weight_lsf);
                elseif ~isempty(ind_bowLV)
                    weight_lsf = [1,weight_lsf_bowLV,1];
                    %[ cx, cy, rinv ] = CircleFitting(app,[p1(1);p2(1);p3(1)],[p1(2);p2(2);p3(2)]);
                    [ cx, cy, rinv ] = CircleFitting_weighted(app,[p1(1);p2(1);p3(1)],[p1(2);p2(2);p3(2)],weight_lsf);
                else
                    weight_lsf = [1,weight_lsf_bowRV,1];
                    %[ cx, cy, rinv ] = CircleFitting(app,[p1(1);p2_2(1);p3(1)],[p1(2);p2_2(2);p3(2)]);                    
                    [ cx, cy, rinv ] = CircleFitting_weighted(app,[p1(1);p2_2(1);p3(1)],[p1(2);p2_2(2);p3(2)],weight_lsf);
                end
                
                p1 = t1arch(1,:);
                p2 = t1arch(floor(end/2),:);
                p3 = t1arch(end,:);
                % 3 point approximation
                [ cx_rvcurve, cy_rvcurve, rinv_rvcurve] = CircleFitting(app,[p1(1);p2(1);p3(1)],[p1(2);p2(2);p3(2)]);
                if cx < mean(t1arch(:,1)) && i == 2 % only for septal curvature
                    rinv_rvcurve = -rinv_rvcurve;
                end
                app.ApproxCircleCenter_rvcurve(currentTimeStep,:) = [cx_rvcurve cy_rvcurve];
            else % free wall curvature
                if norm(t2cen-t1arch1cen) < norm(t2cen-t1arch2cen)
                    t1arch = t1arch2;
                else
                    t1arch = t1arch1;
                end
                p1 = t1arch(1,:);
                p2 = t1arch(floor(end/2),:);
                p3 = t1arch(end,:);
                % 3 point approximation
                [ cx, cy, rinv ] = CircleFitting(app,[p1(1);p2(1);p3(1)],[p1(2);p2(2);p3(2)]);
            end
            % all points
            %[ cx, cy, rinv ] = CircleFitting(app,t1arch(:,1),t1arch(:,2));
            % 3 point approximation
            %[ cx, cy, rinv ] = CircleFitting(app,[p1(1);p2(1);p3(1)],[p1(2);p2(2);p3(2)]);
            
            if cx < mean(t1arch(:,1)) && i == 2 % only for septal curvature
                rinv = -rinv;
            end
            
            app.ArchPoints{currentTimeStep,i} = t1arch;
            app.ApproxCircleCenter(currentTimeStep,i,1) = cx; 
            app.ApproxCircleCenter(currentTimeStep,i,2) = cy; 
            
            if i == 2
                ComputeCurvatureArea(app,1/rinv_rvcurve);
            end
            
        end
        
        function refPointFW = ComputeFreeWallRefPoints(app,deg)
            theta = pi/180 * deg;
            for t = 1 : app.TimeStepSpinner.Limits(2)
                tmp1 = squeeze(squeeze(app.CoordContours(t,1,:,:)));
                tmp2 = squeeze(squeeze(app.CoordContours(t,2,:,:)));
                tmp1(tmp1==0) = [];
                tmp1 = reshape(tmp1,[],2);
                tmp2(tmp2==0) = [];
                tmp2 = reshape(tmp2,[],2);
                v  = mean(tmp1,1) - mean(tmp2,1);
                v = v/norm(v);
                x(1) = (v(1)*cos(theta)-v(2)*sin(theta))/(v(1)^2+v(2)^2);
                y(1) = sin(theta)/v(1)+v(2)/v(1)*x(1);
                x(2) = (v(1)*cos(-theta)-v(2)*sin(-theta))/(v(1)^2+v(2)^2);
                y(2) = sin(-theta)/v(1)+v(2)/v(1)*x(2);
                
%                a  = (v(1)^2+v(2)^2)*cos(theta)^2;
%                b  = sqrt(v(1)*v(1)*v(2)*v(2)-(v(1)^2-a)*(v(2)^2-a));
%                y1 = (-v(1)*v(2) + b)/(v(2)^2-a);
%                y2 = (-v(1)*v(2) - b)/(v(2)^2-a);
                vecs1 = tmp1 - mean(tmp1,1);
                % for y1
                dot1theta = dot(vecs1,repmat([x(1),y(1)],[size(vecs1,1),1]),2);
                magvec1 = sqrt(vecs1(:,1).*vecs1(:,1)+vecs1(:,2).*vecs1(:,2));
                angle = dot1theta./magvec1;
                [~, I] = max(angle);
                refPointFW(t,1,:) = tmp1(I,:);
                % for y2                   
                dot1theta = dot(vecs1,repmat([x(2),y(2)],[size(vecs1,1),1]),2);
                magvec1 = sqrt(vecs1(:,1).*vecs1(:,1)+vecs1(:,2).*vecs1(:,2));
                angle = dot1theta./magvec1;
                [~, I] = max(angle);
                refPointFW(t,2,:) = tmp1(I,:);                
            end
        end
        
        function refPointSep = ComputeSeptalRefPoints(app)
            for t = 1 :app.TimeStepSpinner.Limits(2)
                tmp1 = squeeze(squeeze(app.CoordContours(t,1,:,:)));
                tmp2 = squeeze(squeeze(app.CoordContours(t,2,:,:)));
                tmp1(tmp1==0) = [];
                tmp1 = reshape(tmp1,[],2);
                tmp2(tmp2==0) = [];
                tmp2 = reshape(tmp2,[],2);
                p1  = mean(tmp2,1);            
                p2  = mean(tmp1,1);
                
                indx = tmp1(:,1)<p2(1);
                tmp1_l = tmp1(indx,:);
                indx = tmp2(:,1)>p1(1);
                tmp2_r = tmp2(indx,:);

                a =   p2(2)-p1(2);
                b = -(p2(1)-p1(1));
                c =   p2(1)*p1(2)-p1(1)*p2(2);
                d1 = abs(a*tmp1_l(:,1)+b*tmp1_l(:,2)+c)/sqrt(a*a+b*b);

                [~,ind] = min(d1);
                ind = ind + size(tmp1_l,1);
                tmp1_l = repmat(tmp1_l,[3,1]);
                tmp2_r = repmat(tmp2_r,[3,1]);

                for i = ind-1:-1:1
                    di = sqrt((tmp2_r(:,1)-tmp1_l(i,1)).^2+(tmp2_r(:,2)-tmp1_l(i,2)).^2);
                    if min(di) > 0.4 && i < ind-30
                        refPointSep(t,1,:) = tmp1_l(i+1,:);
                        break;
                    end
                end

                for i = ind+1:size(tmp1_l,1)
                    di = sqrt((tmp2_r(:,1)-tmp1_l(i,1)).^2+(tmp2_r(:,2)-tmp1_l(i,2)).^2);
                    if min(di) > 0.4 && i > ind + 30
                        refPointSep(t,2,:) = tmp1_l(i-1,:);
                        break;
                    end
                end               
            end   
        end
        
        function ComputeCurvatureArea(app,rad)
            
            currentTimeStep = app.TimeStepSpinner.Value;
            
            t1arch          = app.ArchPoints{currentTimeStep,2};
            cx              = app.ApproxCircleCenter_rvcurve(currentTimeStep,1);
            cy              = app.ApproxCircleCenter_rvcurve(currentTimeStep,2);
            refPoints(1,:)  = app.RefPoints(currentTimeStep,1,:);
            refPoints(2,:)  = app.RefPoints(currentTimeStep,2,:);

            step_integral = size(t1arch,1);
            
            % Compute LV area for normalization
            LVepi = squeeze(app.CoordContours(currentTimeStep,1,:,:));
            LVarea = polyarea(LVepi(:,1),LVepi(:,2));
            
            if t1arch(1,2) > t1arch(end,2)
                t1arch = flip(t1arch,1);
            end

            % line segment (approach 1)
            [areaplus,areaminus] = get_linebasedArea(app,t1arch,refPoints,step_integral);

%             if refPoints(1,1) == refPoints(2,1) % if the line to create is x = const.
%                 xq = repmat(refPoints(1,1),[1,step_integral]);
%                 yq = refPoints(1,2) :(refPoints(2,2)-refPoints(1,2))/(step_integral -1) : refPoints(2,2);
%             else                                % if the line to create is y = ax 
%                 xq = refPoints(1,1) :(refPoints(2,1)-refPoints(1,1))/(step_integral -1) : refPoints(2,1);
%                 yq = interp1([refPoints(1,1) refPoints(2,1)],[refPoints(1,2) refPoints(2,2)],xq,'linear');
%             end
%             
%             linsegq = [xq;yq]';
%             
%             % interpolate the arch with the same steps
% %             tarch   = 1:size(t1arch,1);
% %             tarchq  = 1:(size(t1arch,2)-1)/(step_integral-1):size(t1arch,2); 
% %             t1archq(1,:) = spline(tarch,t1arch(:,1),tarchq);
% %             t1archq(2,:) = spline(tarch,t1arch(:,2),tarchq);
%             
%             areaplus  = 0;
%             areaminus = 0;
%             
%             % compute area (cross product)
%             for i = 1 : size(linsegq,1)-1
%                 v2 = t1arch(i  ,:) - linsegq(i+1,:);
%                 v1 = t1arch(i+1,:) - linsegq(i  ,:);
%                
%                 area = 0.5*(v1(1)*v2(2)-v1(2)*v2(1));
%                 if area >= 0
%                     areaplus  = areaplus + area;
%                 else
%                     areaminus = areaminus + area;
%                 end
%             end
            
            app.CurvatureArea(currentTimeStep,1,1) = areaplus;
            app.CurvatureArea(currentTimeStep,1,2) = areaminus;
            app.NormCurvatureArea(currentTimeStep,1,1) = areaplus/LVarea;
            app.NormCurvatureArea(currentTimeStep,1,2) = areaminus/LVarea;

            % compute the arch of the approximated circle (approach 2)
            ang = 0:1/(step_integral-1):2*pi;
            circPoints(1,:) = cx + rad*cos(ang);
            circPoints(2,:) = cy + rad*sin(ang);
            
            dist1 = sqrt((circPoints(1,:) - refPoints(1,1)).^2 + (circPoints(2,:) - refPoints(1,2)).^2);
            dist2 = sqrt((circPoints(1,:) - refPoints(2,1)).^2 + (circPoints(2,:) - refPoints(2,2)).^2);
            
            [~,minind1] = min(dist1);
            [~,minind2] = min(dist2);
            
            clear circPoints
            
            %if (minind2 - minind1) < 0
                ang_arch = ang(minind1):(ang(minind2)-ang(minind1))/(step_integral -1):ang(minind2);
            %else
            %    ang_arch = ang(minind2):(ang(minind1)-ang(minind2))/(step_integral -1):ang(minind1);
            %end
            
            circPoints(:,1) = cx + rad*cos(ang_arch);
            circPoints(:,2) = cy + rad*sin(ang_arch);            

            areaplus  = 0;
            areaminus = 0;
            % compute area (cross product)
            for i = 1 : size(circPoints,1)-1
                v2 = t1arch(i  ,:) - circPoints(i+1,:);
                v1 = t1arch(i+1,:) - circPoints(i  ,:);

                area = 0.5*(v1(1)*v2(2)-v1(2)*v2(1));
                if area >= 0
                    areaplus  = areaplus + area;
                else
                    areaminus = areaminus + area;
                end
            end
            
            app.CurvatureArea(currentTimeStep,2,1) = areaplus;
            app.CurvatureArea(currentTimeStep,2,2) = areaminus;
            app.NormCurvatureArea(currentTimeStep,2,1) = areaplus/LVarea;
            app.NormCurvatureArea(currentTimeStep,2,2) = areaminus/LVarea;
            
        end
        
        function GetSeptalCurvature_allPhases(app)
            if strcmp(app.ReferencepointsDropDown.Value, 'Automatic')
                refPointSep = ComputeSeptalRefPoints(app);
            end
            currentTimeStep = app.TimeStepSpinner.Value;
            for t = 1 : size(app.CoordContours,1)
                app.TimeStepSpinner.Value = t;
                % for septal
                if strcmp(app.ReferencepointsDropDown.Value, 'Automatic')
                    points{1}(1,:) = refPointSep(t,1,:);   % method 2: use automated hinge point detection
                    points{2}(1,:) = refPointSep(t,2,:);
                else
                    points{1}(1,:) = app.RefPoints(t,1,:);  % method 1: use user-specified point (on cvi42)
                    points{2}(1,:) = app.RefPoints(t,2,:);
                end
                app.CurvatureSeptum(t,1) = ComputeCurvature(app,points,2,1);
            end
            app.TimeStepSpinner.Value = currentTimeStep;
            drawContours(app,currentTimeStep);
            updateCurvaturePlot(app,app.CurvatureSeptum(:,1),app.CurvatureFreeWall(:,1));
        end
        
        function read_saxDicom(app, dirDcm)
            % read DICOM data to re-arrange temporal contours.
            % with assumption that Dicom files have correct temporal orders.
            % Dicom and cvi output can be linked by SOP Instance UID.
            if nargin < 2
                dirDcm = uigetdir('','Select a folder that includes dicom files used for contour delineation');
                [~ ,app.ptsdirname, ~] = fileparts(fileparts(dirDcm));
            end
            dirDcmFiles = dir(dirDcm);
            dirDcmFiles = dirDcmFiles(~ismember({dirDcmFiles.name},{'.','..','.DS_Store'}));
            img = cell(length(dirDcmFiles),1);
            tag = cell(length(dirDcmFiles),1);
            cnt = 1;
            for i = 1 : length(dirDcmFiles)
                tag                   = dicominfo(fullfile(dirDcmFiles(i).folder,dirDcmFiles(i).name),'dictionary','dicom-dict_philips.txt');          
%                 if ~any(contains(SOP_cvi,tag.SOPInstanceUID(end-9:end)))
%                     continue;
%                 end
                app.SAXimg{cnt}         = dicomread(fullfile(dirDcmFiles(i).folder,dirDcmFiles(i).name));
                app.SOPInstanceUID{cnt} = tag.SOPInstanceUID;
                app.PixelSpacing      = tag.PixelSpacing;
                cnt = cnt + 1;
                app.messagetxt.Value = ['Reading SAX dicom...(',num2str(ceil(i/length(dirDcmFiles)*100)),'%)'];
                pause(0.01);
            end
            
%             % use SOPInstanceUID to reorder temporal dataf 
%             indtotal = zeros(size(app.SOPInstanceUID));
%             if length(SOP_cvi) ~= length(app.SOPInstanceUID)
%                 for i = 1 : size(SOP_cvi,1)
%                     ind = contains(app.SOPInstanceUID,SOP_cvi{i});
%                     indtotal = indtotal + ind;
%                 end
%                 app.SOPInstanceUID = app.SOPInstanceUID(logical(indtotal));
%                 app.SAXimg = app.SAXimg(logical(indtotal));
%             end
            
%             for i = 1 : size(SOP_cvi,1)
%                 tstep = contains(SOP_cvi,app.SOPInstanceUID{i}(end-9:end));
%                 I(i,1) = find(tstep);
%             end
%             
%             % reorder temporal data
%             app.CoordContours = app.CoordContours(I,:,:,:);
%             app.RefPoints = app.RefPoints(I,:,:);

        end
        
        function exportCurvatureToXlsx(app,ptFolderName,xlpath,xlname,ip)
            if nargin == 1
                saveOpt =  questdlg("Save to a new file or to existing file?","Save options","New file","Existing file","New file");
                switch saveOpt
                    case "New file"
                        if_addToExist = 0;
                    case "Existing file"
                        if_addToExist = 1;
                end

                prompt = {['Enter a name to spacify this patient (If you select "Existing file" and use an already existing sheet name, ' ...
                    'the result will be appended to the bottom of that sheet, not creating a new sheet.']};
                dlgtitle = "Export file name dialog";
                definput = {app.ptsdirname};
                answer = inputdlg(prompt,dlgtitle,1,definput);
            end
            
            if nargin == 5
                if_addToExist = 1;    
                answer{1} = ptFolderName;
            end
            
            if nargin ~= 1 && nargin ~= 5
                error('Number of input argments should be 1 or 4');
            end
            
            if strcmpi(app.PointsforcirclefittingDropDown.Value,'Auto')
                mode = 1;
            else
                mode = 2;
            end
            % create a table
            Time_Step = [1:app.TimeStepSpinner.Limits(2)]';
            Septal_Curvature      = app.CurvatureSeptum(:,mode);
            Free_Wall_Curvature   = app.CurvatureFreeWall(:,mode);
            Curvature_Ratio       = app.CurvatureRatio;
            if mode == 1
                Curvature_Area_Linebase_plus   =app.CurvatureArea(:,1,1);
                Curvature_Area_Linebase_minus  =app.CurvatureArea(:,1,2);
                Curvature_Area_Curvebase_plus  =app.CurvatureArea(:,2,1);
                Curvature_Area_Curvebase_minus =app.CurvatureArea(:,2,2);
                NormCurvature_Area_Linebase_plus   =app.NormCurvatureArea(:,1,1);
                NormCurvature_Area_Linebase_minus  =app.NormCurvatureArea(:,1,2);
                NormCurvature_Area_Curvebase_plus  =app.NormCurvatureArea(:,2,1);
                NormCurvature_Area_Curvebase_minus =app.NormCurvatureArea(:,2,2);

                output = table(Time_Step,Septal_Curvature,Free_Wall_Curvature,Curvature_Ratio,...
                               Curvature_Area_Linebase_plus,Curvature_Area_Linebase_minus,...
                               Curvature_Area_Curvebase_plus,Curvature_Area_Curvebase_minus,...
                               NormCurvature_Area_Linebase_plus,NormCurvature_Area_Linebase_minus,...
                               NormCurvature_Area_Curvebase_plus,NormCurvature_Area_Curvebase_minus);
            else
                output = table(Time_Step,Septal_Curvature,Free_Wall_Curvature,Curvature_Ratio);
            end

            if if_addToExist == 0
                saveloc = uigetdir(pwd,"Choose location to save excel file.");
                savefile = [answer{1},'.xlsx'];
                writetable(output,fullfile(saveloc,savefile),'Sheet',answer{1});
            else
                if nargin == 1 % individual
                    [savefile,saveloc] = uigetfile("","Choose a spreadsheet to add this patient.");
                end
                if nargin == 5 % batch
                    savefile = xlname;
                    saveloc  = xlpath;
                end
                writetable(output,fullfile(saveloc,savefile),'Sheet',answer{1},'WriteMode',"append");
            end
            
            if nargin == 5 % statistics
                stat{1,1} = answer{1};
                stat{1,2} = sum(Curvature_Ratio);
                [~,indmin] = min(Curvature_Ratio);
                Curvature_Ratio_repeat = repmat(Curvature_Ratio,3,1);
                stat{1,3} = mean(Curvature_Ratio_repeat(length(Curvature_Ratio)+indmin-1:length(Curvature_Ratio)+indmin+1));
                stat{1,4} = mean(Curvature_Ratio_repeat(length(Curvature_Ratio)+indmin-2:length(Curvature_Ratio)+indmin+2));
                stat{1,5} = mean(Curvature_Ratio_repeat(length(Curvature_Ratio)+indmin-3:length(Curvature_Ratio)+indmin+3));
                stat{1,6} = min(Curvature_Ratio);
                stat{1,7} = max(Curvature_Ratio);
                stat{1,8} = mean(Curvature_Ratio);
                if mode == 1
                    stat{1,9} = sum(Curvature_Area_Linebase_plus);
                    stat{1,10} = min(Curvature_Area_Linebase_plus);
                    stat{1,11} = max(Curvature_Area_Linebase_plus);
                    stat{1,12} = mean(Curvature_Area_Linebase_plus);
                    stat{1,13} = sum(Curvature_Area_Linebase_minus);
                    stat{1,14} = min(Curvature_Area_Linebase_minus);
                    stat{1,15} = max(Curvature_Area_Linebase_minus);
                    stat{1,16} = mean(Curvature_Area_Linebase_minus);
                    stat{1,17} = sum(Curvature_Area_Curvebase_plus);
                    stat{1,18} = min(Curvature_Area_Curvebase_plus);
                    stat{1,19} = max(Curvature_Area_Curvebase_plus);
                    stat{1,20} = mean(Curvature_Area_Curvebase_plus);
                    stat{1,21} = sum(Curvature_Area_Curvebase_minus);
                    stat{1,22} = min(Curvature_Area_Curvebase_minus);
                    stat{1,23} = max(Curvature_Area_Curvebase_minus);
                    stat{1,24} = mean(Curvature_Area_Curvebase_minus);
                    stat{1,25} = sum(NormCurvature_Area_Linebase_plus);
                    stat{1,26} = min(NormCurvature_Area_Linebase_plus);
                    stat{1,27} = max(NormCurvature_Area_Linebase_plus);
                    stat{1,28} = mean(NormCurvature_Area_Linebase_plus);
                    stat{1,29} = sum(NormCurvature_Area_Linebase_minus);
                    stat{1,30} = min(NormCurvature_Area_Linebase_minus);
                    stat{1,31} = max(NormCurvature_Area_Linebase_minus);
                    stat{1,32} = mean(NormCurvature_Area_Linebase_minus);
                    stat{1,33} = sum(NormCurvature_Area_Curvebase_plus);
                    stat{1,34} = min(NormCurvature_Area_Curvebase_plus);
                    stat{1,35} = max(NormCurvature_Area_Curvebase_plus);
                    stat{1,36} = mean(NormCurvature_Area_Curvebase_plus);
                    stat{1,37} = sum(NormCurvature_Area_Curvebase_minus);
                    stat{1,38} = min(NormCurvature_Area_Curvebase_minus);
                    stat{1,39} = max(NormCurvature_Area_Curvebase_minus);
                    stat{1,40} = mean(NormCurvature_Area_Curvebase_minus);

                    rangechr = ['A',num2str(ip+1),':','AK',num2str(ip+1)];
                else
                    rangechr = ['A',num2str(ip+1),':','E',num2str(ip+1)];
                end                    
                writecell(stat,fullfile(xlpath,xlname),'Sheet','Summary','Range',rangechr)
            end
            
        end
        
        function tf_concav = findConcaveShape(app,arch,a,window,dist)
            % smoothing twice with Gaussian filter
            smdata = smoothdata(smoothdata(arch,'gaussian'),'gaussian');
            %smdata = smoothdata(arch,'gaussian');
            % basis
            if ~isinf(a)
                nx = [1 a];
                nx = nx/norm(nx);
                ny = [-nx(2) nx(1)];
            else
                nx = [0 1];
                ny = [1 0];
            end

            curve_in_coord_b = ([nx' ny']'*smdata')';
            dydx = gradient(curve_in_coord_b(:,2))./ gradient(curve_in_coord_b(:,1));
            d2ydx2 = gradient(dydx)./ gradient(curve_in_coord_b(:,1));
            ind_inflect_inner = find(d2ydx2(1:end-1).*d2ydx2(2:end)<0);
            ind_inflect = [0;ind_inflect_inner;size(arch,1)]; % add 0 for the loop later
            if isempty(ind_inflect_inner)
                tf_concav(size(arch,1),1) = false;
            else
                % go back to image coordinate and find the point convex to LV
                for ip = 1 : length(ind_inflect_inner) + 1
                    %[~,indmin] = min((arch(floor((ind_inflect(ip)+1+ind_inflect(ip+1))/2),1) - chord(:,1)).^2 + (arch(floor((ind_inflect(ip)+1+ind_inflect(ip+1))/2),2) - chord(:,2)).^2);
                    if a > 0 && mean(d2ydx2(ind_inflect(ip)+1:ind_inflect(ip+1))) > 0 || a < 0 && mean(d2ydx2(ind_inflect(ip)+1:ind_inflect(ip+1))) < 0
                        tf_concav(ind_inflect(ip)+1:ind_inflect(ip+1),1) = true;
                    else
                        tf_concav(ind_inflect(ip)+1:ind_inflect(ip+1),1) = false;

                    end
                end
            end
        end
        
        function [cx,cy,rinv] = CircleFitting_weighted(app,x0,y0,weight)
            % create matrices
            v = x0.^2 + y0.^2;
            A = [x0  y0  ones(size(x0))];
            % weight (diagonal)
            W = diag(weight);
            x = (transpose(A)*W*A)\transpose(A)*W*v;
            cx = x(1)/2;
            cy = x(2)/2;
            r = sqrt(x(3)+cx^2+cy^2);
            rinv = 1/r;
        end
        
        function [areaplus,areaminus] = get_linebasedArea(app,t1arch,refPoints,step_integral)
            
            if refPoints(1,1) == refPoints(2,1) % if the line to create is x = const.
                xq = repmat(refPoints(1,1),[1,step_integral]);
                yq = refPoints(1,2) :(refPoints(2,2)-refPoints(1,2))/(step_integral -1) : refPoints(2,2);
            else                                % if the line to create is y = ax
                xq = refPoints(1,1) :(refPoints(2,1)-refPoints(1,1))/(step_integral -1) : refPoints(2,1);
                yq = interp1([refPoints(1,1) refPoints(2,1)],[refPoints(1,2) refPoints(2,2)],xq,'linear');
            end

            linsegq = [xq;yq]';

            % interpolate the arch with the same steps
            %             tarch   = 1:size(t1arch,1);
            %             tarchq  = 1:(size(t1arch,2)-1)/(step_integral-1):size(t1arch,2);
            %             t1archq(1,:) = spline(tarch,t1arch(:,1),tarchq);
            %             t1archq(2,:) = spline(tarch,t1arch(:,2),tarchq);

            areaplus  = 0;
            areaminus = 0;

            % compute area (cross product)
            for i = 1 : size(linsegq,1)-1
                v2 = t1arch(i  ,:) - linsegq(i+1,:);
                v1 = t1arch(i+1,:) - linsegq(i  ,:);

                area = 0.5*(v1(1)*v2(2)-v1(2)*v2(1));
                if area >= 0
                    areaplus  = areaplus + area;
                else
                    areaminus = areaminus + area;
                end
            end
        end
        
        function reset_plotviews(app,axhandle)
                limx = get(axhandle,'xlim');
                h = findobj(axhandle,'Type','line');
                delete(h);
                xlim(axhandle,limx);
        end
        
        function drawSpline_tx_ty(app, x, y, nsplit,colorchar)
            % draw spline curves on the contour view using a parameter t
            t = 0:1/(length(x)-1):1;
            ts = 0:1/(nsplit-1):1;
            xs = spline(t,x,ts);
            ys = spline(t,y,ts);
            pl = plot(app.ContourView,xs,ys,[colorchar,'-'],'LineWidth',1.5);
            set(pl,'HitTest','off');
        end

        function registration_points(app)
            % registration
            % looping
            for t = 1 : app.TimeStepSpinner.Limits(2)-1
                img_to_register = app.SAXimg{1};
                img_ref         = app.SAXimg{t+1};
                % get the displacement matrix between t and t+1
                [D,~] = imregdemons(img_to_register,img_ref,[100,50,25],'AccumulatedFieldSmoothing',1.0,'DisplayWaitbar',false);
                % apply the displacement matrix to the points
                for ic = 1 : 2
                    for ip = 1 : size(app.Points{ic,t},1)
                        pixelx = app.Points{ic,1}(ip,1)/app.PixelSpacing(2);
                        pixely = app.Points{ic,1}(ip,2)/app.PixelSpacing(1);
                        dispx = D(floor(pixely):ceil(pixely),floor(pixelx):ceil(pixelx),1);
                        dispy = D(floor(pixely):ceil(pixely),floor(pixelx):ceil(pixelx),2);
                        pixelx_registered = pixelx - mean(dispx(:));
                        pixely_registered = pixely - mean(dispy(:));
                        app.Points{ic,t+1}(ip,1) = pixelx_registered*app.PixelSpacing(2);
                        app.Points{ic,t+1}(ip,2) = pixely_registered*app.PixelSpacing(1);
                    end
                end
                app.messagetxt.Value = ['Doing registration in phase (',num2str(t+1,'%0d'),'/',num2str(app.TimeStepSpinner.Limits(2),'%0d'),')...'];
                pause(0.01);
                % update contour view
                app.TimeStepSpinner.Value = t+1;
                currentTimeStep = t+1;
                drawManualCurves(app,currentTimeStep);
            end
            % compute curvatures and update plot (re-activate timeStepSpinner)
            for t = 1 : app.TimeStepSpinner.Limits(2)
                [  ~,  ~, rinv ] = CircleFitting(app,app.Points{1,t}(:,1),app.Points{1,t}(:,2));
                app.CurvatureFreeWall(t,2) = rinv;
                [ cx, cy, rinv ] = CircleFitting(app,app.Points{2,t}(:,1),app.Points{2,t}(:,2));
                sept_to_freewall = mean(app.Points{1,t},1)-mean(app.Points{2,t},1);
                sept_to_center   = [cx cy] - mean(app.Points{2,t},1);
                if dot(sept_to_center,sept_to_freewall) > 0
                    app.CurvatureSeptum(t,2)   = rinv;
                else
                    app.CurvatureSeptum(t,2)   = -rinv;
                end
            end
            set(app.TimeStepSpinner,'Enable','on');
            app.registrationDone = true;
            drawManualCurves(app,currentTimeStep);
            updateCurvaturePlot(app,app.CurvatureSeptum(:,2),app.CurvatureFreeWall(:,2));
        end

        function drawManualCurves(app,currentTimeStep)
            hold(app.ContourView, 'off');
            hdl = imshow(app.SAXimg{currentTimeStep},[0 512],'Parent',app.ContourView, ...
                'Xdata',[1 size(app.SAXimg{currentTimeStep},2)*app.PixelSpacing(1)], ...
                'Ydata',[1 size(app.SAXimg{currentTimeStep},1)*app.PixelSpacing(2)]);
            set(hdl,'HitTest','off');
            hold(app.ContourView, 'on');
            pl1 = plot(app.ContourView,app.Points{1,currentTimeStep}(:,1),app.Points{1,currentTimeStep}(:,2),'go');
            pl2 = plot(app.ContourView,app.Points{2,currentTimeStep}(:,1),app.Points{2,currentTimeStep}(:,2),'ro');
            set(pl1,'HitTest','off');
            set(pl2,'HitTest','off');
            drawSpline_tx_ty(app, app.Points{1,currentTimeStep}(:,1), app.Points{1,currentTimeStep}(:,2), 100,'g');
            drawSpline_tx_ty(app, app.Points{2,currentTimeStep}(:,1), app.Points{2,currentTimeStep}(:,2), 100,'r');
        end
        
        function loadManualpoints_computeCurvature(app,mptsFileLoc)
            if nargin < 2
                if ispc
                    [filename, pathToFile] = uigetfile('','Select the manualPoint file.','*.csv');
                else
                    [filename, pathToFile] = uigetfile('*');
                end
            else % batch
                [pathToFile,filename,~] = fileparts(mptsFileLoc);
            end

            T = readtable(fullfile(pathToFile,filename));
            allpoints_xy = table2array(T);
            allpoints_xy(:,1) = [];
            for t = 1 : app.TimeStepSpinner.Limits(2)
                allpoints_xy_t = allpoints_xy(t,:);
                allpoints_xy_t = reshape(allpoints_xy_t,2,[])';
                app.Points{1,t} = allpoints_xy_t(1:3,:);
                app.Points{2,t} = allpoints_xy_t(4:6,:);
            end
            drawManualCurves(app,app.TimeStepSpinner.Value);
            app.registrationDone = true;
            app.PointsforcirclefittingDropDown.Value = 'Manual';
            app.messagetxt.Value = 'Manual points loaded.';
            set(app.TimeStepSpinner,'Enable','on');
            % compute curvature
            for t = 1 : app.TimeStepSpinner.Limits(2)
                [  ~,  ~, rinv ] = CircleFitting(app,app.Points{1,t}(:,1),app.Points{1,t}(:,2));
                app.CurvatureFreeWall(t,2) = rinv;
                [ cx, cy, rinv ] = CircleFitting(app,app.Points{2,t}(:,1),app.Points{2,t}(:,2));
                sept_to_freewall = mean(app.Points{1,t},1)-mean(app.Points{2,t},1);
                sept_to_center   = [cx cy] - mean(app.Points{2,t},1);
                if dot(sept_to_center,sept_to_freewall) > 0
                    app.CurvatureSeptum(t,2)   = rinv;
                else
                    app.CurvatureSeptum(t,2)   = -rinv;
                end
            end
            updateCurvaturePlot(app,app.CurvatureSeptum(:,2),app.CurvatureFreeWall(:,2));
        end
        
        function saveManualpoints(app,saveDir)
            if ~isempty(app.Points)
                if nargin < 2
                    dirloc = uigetdir('','Select directory to save points.');
                else
                    dirloc = saveDir;
                end
                savefilename = fullfile(dirloc,[app.ptsdirname,'_manualPoints.csv']);
                allpoints_xy = [];
                for t = 1 : app.TimeStepSpinner.Limits(2)
                    allpoints_xy_t = [app.Points{1,t};app.Points{2,t}];
                    allpoints_xy_t = reshape(allpoints_xy_t',1,[]);
                    allpoints_xy = [allpoints_xy;allpoints_xy_t];
                end
                timeStep = 1 : app.TimeStepSpinner.Limits(2);
                T = array2table(allpoints_xy);
                T.Properties.VariableNames = {'Freewall_x1','Freewall_y1','Freewall_x2','Freewall_y2','Freewall_x3','Freewall_y3','Septal_x1','Septal_y1','Septal_x2','Septal_y2','Septal_x3','Septal_y3'};
                T = addvars(T,timeStep','Before',"Freewall_x1",'NewVariableNames','TimeSteps');
                writetable(T,savefilename);
                app.messagetxt.Value = 'Manual Point saved.';
            else
                app.messagetxt.FontColor = 'r';
                app.messagetxt.Value = 'Manual points do not exist.';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadContourButton
        function LoadContourButtonPushed(app, event)
            % reset all the shared variables
            app.CoordContours               = [];
            app.PixelSize                   = [];
            app.UserSpecifiedSeptalPoints   = [];
            app.UserSpecifiedFreeWallPoints = [];
            app.PixelSize                   = [];
            app.CurvatureSeptum             = [];
            app.CurvatureFreeWall           = [];
            app.CurvatureRatio              = [];
            app.Points                      = [];
            app.RefPoints                   = [];
            app.ArchPoints                  = [];
            app.ApproxCircleCenter          = [];
            app.ApproxCircleCenter_rvcurve  = [];
            app.SubPixRes                   = [];
            app.CurvatureArea               = [];
            app.SOPInstanceUID              = [];
            app.NormCurvatureArea           = [];
            app.RefPoints_midSepWall        = []; 
            app.weightParam                 = [];    
            app.registrationDone            = false;
            app.pointToMove                 = [];
            app.ptsdirname                  = [];

            app.initialCall = 1;
            app.CountPointsSeptal           = 0;
            app.CountPointsFreewall         = 0;
 
            app.messagetxt.FontColor = 'w';
            app.messagetxt.Value = 'Auto mode.';
            app.PointsforcirclefittingDropDown.Value = 'Auto';

            read_saxDicom(app);           % data import: read DICOM first 
            contourReader_cvifile(app);   % data import: then read a cvi file
            app.TimeStepSpinner.Limits = [1,size(app.CoordContours,1)];
            axis(app.ContourView,'equal');
            axis(app.ContourView,'ij');
            xlim(app.CurvatureView,app.TimeStepSpinner.Limits);
            xlim(app.CurvatureRatioView,app.TimeStepSpinner.Limits);
            xlim(app.CurvatureAreaView,app.TimeStepSpinner.Limits);

            % Draw for Free wall
            refPointFW  = ComputeFreeWallRefPoints(app,100); % set as 80[deg]. Need validation
            for t = 1 : size(app.CoordContours,1)
                app.TimeStepSpinner.Value = t;
                % for free wall
                points{1}(1,:) = refPointFW(t,1,:);
                points{2}(1,:) = refPointFW(t,2,:);
                app.CurvatureFreeWall(t,1) = ComputeCurvature(app,points,1,2);
            end

            % Draw initial curvature plot using the reference points (Septal)
            GetSeptalCurvature_allPhases(app);            
            app.TimeStepSpinner.Value = 1;
        end

        % Value changed function: TimeStepSpinner
        function TimeStepSpinnerValueChanged(app, event)
            currentTimeStep = app.TimeStepSpinner.Value;
            if strcmpi(app.PointsforcirclefittingDropDown.Value,'Auto')
                drawContours(app,currentTimeStep);
                updateCurvaturePlot(app,app.CurvatureSeptum(:,1),app.CurvatureFreeWall(:,1));
            elseif strcmpi(app.PointsforcirclefittingDropDown.Value,'Manual')
                drawManualCurves(app,currentTimeStep);
                updateCurvaturePlot(app,app.CurvatureSeptum(:,2),app.CurvatureFreeWall(:,2));
            end

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            exportCurvatureToXlsx(app)
        end

        % Value changed function: CurvatureareaDropDown
        function CurvatureareaDropDownValueChanged(app, event)
            updateCurvaturePlot(app,app.CurvatureSeptum(:,1),app.CurvatureFreeWall(:,1));           
        end

        % Value changed function: ReferencepointsDropDown
        function ReferencepointsDropDownValueChanged(app, event)
            GetSeptalCurvature_allPhases(app);
        end

        % Button pushed function: BatchanalysisButton
        function BatchanalysisButtonPushed(app, event)
            % select the directory that includes subject data
            pathToDir = uigetdir('','Select a folder that includes patient data!');
            outXlDirName = uigetdir('','Choose the location to save output files!');
            
            % manual options
            if strcmpi(app.PointsforcirclefittingDropDown.Value,'Manual')
                %existing_or_new = questdlg('Use existing manual data, or draw new curve segments?','Manual mode menu','Use existing data','Draw new','Use existing data');
                %switch existing_or_new
                %    case 'Use existing data'
                        pathToPoints = uigetdir('','Select a folder that includes manualPoints files');
                        filelist_mpts = dir(pathToPoints);
                        filelist_mpts = filelist_mpts(~ismember({filelist_mpts.name},{'.','..','.DS_Store'}));
                        filelist_mpts = filelist_mpts(~[filelist_mpts.isdir]);
                %    case 'Draw new'
                %        pathToPoints = uigetdir('','Select a folder where you want to save manual data');                        
                %end
            end
            
            % get subject directories and remove unnecessary files
            listPts = dir(pathToDir);
            listPts = listPts(~ismember({listPts.name},{'.','..','.DS_Store'}));
            listPts = listPts([listPts.isdir]);
            
            % Properties of the output xlsx file
            outXlFileName = char(['batch_septalCurvatures_n',num2str(length(listPts),'%0d'),'_',char(datetime('now','Format','yyMMdd_HHmmss')),'.xlsx']); 
            listClmn = {'Patient','CurvatureRatio_sum','CurvatureRatio_min_movavg_3','CurvatureRatio_min_movavg_5','CurvatureRatio_min_movavg_7','CurvatureRatio_min','CurvatureRatio_max','CurvatureRatio_avg',...
                                  'linebasePlus_sum',  'linebasePlus_min',  'linebasePlus_max',  'linebasePlus_avg',...   
                                  'linebaseMinus_sum', 'linebaseMinus_min', 'linebaseMinus_max', 'linebaseMinus_avg',...   
                                  'curvebasePlus_sum',  'curvebasePlus_min',  'curvebasePlus_max',  'curvebasePlus_avg',...   
                                  'curvebaseMinus_sum', 'curvebaseMinus_min', 'curvebaseMinus_max', 'curvebaseMinus_avg',...
                                  'normlinebasePlus_sum',  'normlinebasePlus_min',  'normlinebasePlus_max',  'normlinebasePlus_avg',...   
                                  'normlinebaseMinus_sum', 'normlinebaseMinus_min', 'normlinebaseMinus_max', 'normlinebaseMinus_avg',...   
                                  'normcurvebasePlus_sum',  'normcurvebasePlus_min',  'normcurvebasePlus_max',  'normcurvebasePlus_avg',...   
                                  'normcurvebaseMinus_sum', 'normcurvebaseMinus_min', 'normcurvebaseMinus_max', 'normcurvebaseMinus_avg'};

            writecell(listClmn,fullfile(outXlDirName,outXlFileName),'Sheet','Summary','Range','A1:AK1');
            h = waitbar(0,'Processing...');
            for ip = 1 : length(listPts)
                % reset all the shared variables
                app.CoordContours               = [];
                app.PixelSize                   = [];
                app.UserSpecifiedSeptalPoints   = [];
                app.UserSpecifiedFreeWallPoints = [];
                app.PixelSize                   = [];
                app.CurvatureSeptum             = [];
                app.CurvatureFreeWall           = [];
                app.CurvatureRatio              = [];
                %app.CountPointsSeptal           = [];
                %app.CountPointsFreewall         = [];
                app.Points                      = [];
                app.RefPoints                   = [];
                app.ArchPoints                  = [];
                app.ApproxCircleCenter          = [];
                app.ApproxCircleCenter_rvcurve  = [];
                app.SubPixRes                   = [];
                app.CurvatureArea               = [];
                app.SOPInstanceUID              = [];
                app.NormCurvatureArea           = [];
                app.RefPoints_midSepWall        = []; 
                app.weightParam                 = [];    
                app.registrationDone            = false;
                app.pointToMove                 = [];
                app.ptsdirname                  = [];
                app.initialCall = 1;

                app.messagetxt.FontColor = 'w';
                app.messagetxt.Value = ['Batch analysis with ',app.PointsforcirclefittingDropDown.Value,' mode .'];
                pause(0.05);

                file_cvi42 = dir(fullfile(listPts(ip).folder,listPts(ip).name,'*.cvi42wsx'));
                dirDcm = dir(fullfile(listPts(ip).folder,listPts(ip).name));
                dirDcm  = dirDcm(~ismember({dirDcm.name},{'.','..','.DS_Store'}));
                dirDcm  = dirDcm([dirDcm.isdir]);
                
                read_saxDicom(app,fullfile(dirDcm.folder,dirDcm.name));
                contourReader_cvifile(app,file_cvi42.name,file_cvi42.folder);
                app.TimeStepSpinner.Limits = [1,size(app.CoordContours,1)]; % need this for batch?
                axis(app.ContourView,'equal');                              % need this for batch?
                axis(app.ContourView,'ij');                                 % need this for batch?
                xlim(app.CurvatureView,app.TimeStepSpinner.Limits);
                xlim(app.CurvatureRatioView,app.TimeStepSpinner.Limits);
                xlim(app.CurvatureAreaView,app.TimeStepSpinner.Limits);
                
                if strcmpi(app.PointsforcirclefittingDropDown.Value,'Auto')
                    % Draw for Free wall
                    refPointFW  = ComputeFreeWallRefPoints(app,100); % set as 80[deg]. Need validation
                    for t = 1 : size(app.CoordContours,1)
                        app.TimeStepSpinner.Value = t;
                        % for free wall
                        points{1}(1,:) = refPointFW(t,1,:);
                        points{2}(1,:) = refPointFW(t,2,:);
                        app.CurvatureFreeWall(t,1) = ComputeCurvature(app,points,1,2);
                    end

                    % Draw initial curvature plot using the reference points (Septal)
                    GetSeptalCurvature_allPhases(app);
                    app.TimeStepSpinner.Value = 1;
                else % manual
                    if_exists = contains({filelist_mpts.name},listPts(ip).name);
                    if any(if_exists)
                        % Load manualPoints flies and compute curvature
                        loadManualpoints_computeCurvature(app,fullfile(filelist_mpts(if_exists).folder,filelist_mpts(if_exists).name));
                        %updateCurvaturePlot(app,app.CurvatureSeptum(:,2),app.CurvatureFreeWall(:,2));
                    else 
                        warning('manualPoint does not exist for this case. Skipping...');
                    end
                end
                exportCurvatureToXlsx(app,listPts(ip).name,outXlDirName,outXlFileName,ip);
                waitbar(ip/length(listPts),h,['Processed ',num2str(ip,'%0d'),' of ',num2str(length(listPts)),' subjects...']);
            end
            close(h);
            h = msgbox('Finished!');            
        end

        % Value changed function: PointsforcirclefittingDropDown
        function PointsforcirclefittingDropDownValueChanged(app, event)
            value = app.PointsforcirclefittingDropDown.Value;

            if app.initialCall == 0 && strcmp(value,'Manual')
                app.messagetxt.FontColor = 'w';
                app.messagetxt.Value = 'Manual mode activated. Place 3 points at the septal wall at the initial time step.';
                if ~app.registrationDone
                    app.TimeStepSpinner.Value = 1;
                    currentTimeStep = app.TimeStepSpinner.Value;

                    set(app.TimeStepSpinner,'Enable','off');
                    % delete contours and show only images
                    hold(app.ContourView, 'off');
                    hdl = imshow(app.SAXimg{currentTimeStep},[0 512],'Parent',app.ContourView, ...
                        'Xdata',[1 size(app.SAXimg{currentTimeStep},2)*app.PixelSpacing(1)], ...
                        'Ydata',[1 size(app.SAXimg{currentTimeStep},1)*app.PixelSpacing(2)]);
                    set(hdl,'HitTest','off');
                    hold(app.ContourView, 'on');
                    % reset plot panes
                    reset_plotviews(app,app.CurvatureView);
                    reset_plotviews(app,app.CurvatureRatioView);
                    reset_plotviews(app,app.CurvatureAreaView);
                else
                    currentTimeStep = app.TimeStepSpinner.Value;
                    drawManualCurves(app,currentTimeStep);
                    updateCurvaturePlot(app,app.CurvatureSeptum(:,2),app.CurvatureFreeWall(:,2));
                    reset_plotviews(app,app.CurvatureAreaView);  
                    app.messagetxt.Value = 'Please correct curves at all phases by dragging control points.';
                end
            elseif app.initialCall == 0 && strcmp(value,'Auto')
                % go back to auto mode
                set(app.TimeStepSpinner,'Enable','on');
                currentTimeStep = app.TimeStepSpinner.Value;
                app.messagetxt.FontColor = 'w';
                app.messagetxt.Value = 'Auto mode.';
                drawContours(app,currentTimeStep);
                updateCurvaturePlot(app,app.CurvatureSeptum(:,1),app.CurvatureFreeWall(:,1));
                app.CountPointsSeptal   = 0;
                app.CountPointsFreewall = 0;
            end
        end

        % Button down function: ContourView
        function ContourViewButtonDown(app, event)
            currentTimeStep = app.TimeStepSpinner.Value;
            if strcmpi(app.PointsforcirclefittingDropDown.Value,'Manual')
                if ~app.registrationDone
                    if app.CountPointsSeptal < 3
                        hold(app.ContourView,'on');
                        app.CountPointsSeptal = app.CountPointsSeptal + 1;
                        app.Points{2,currentTimeStep}(app.CountPointsSeptal,:) = app.ContourView.CurrentPoint(1,1:2);
                        plot(app.ContourView,app.ContourView.CurrentPoint(1,1),app.ContourView.CurrentPoint(1,2),'ro');
                        if app.CountPointsSeptal == 3
                            drawSpline_tx_ty(app,app.Points{2,currentTimeStep}(:,1),app.Points{2,currentTimeStep}(:,2),100,'r');
                            app.messagetxt.Value = 'Place 3 points at the LV free wall';
                        end
                    elseif app.CountPointsSeptal == 3 && app.CountPointsFreewall < 3
                        app.messagetxt.FontColor = 'y';
                        app.CountPointsFreewall = app.CountPointsFreewall + 1;
                        app.Points{1,currentTimeStep}(app.CountPointsFreewall,:) = app.ContourView.CurrentPoint(1,1:2);
                        plot(app.ContourView,app.ContourView.CurrentPoint(1,1),app.ContourView.CurrentPoint(1,2),'go');
                        pause(0.1);
                        if app.CountPointsFreewall == 3
                            drawSpline_tx_ty(app,app.Points{1,currentTimeStep}(:,1),app.Points{1,currentTimeStep}(:,2),100,'g');
                            app.messagetxt.Value = ['Doing registration in phase (1/',num2str(app.TimeStepSpinner.Limits(2),'%0d'),')...'];
                            % registration
                            registration_points(app);
                            app.CountPointsSeptal   = 0;
                            app.CountPointsFreewall = 0;
                            app.messagetxt.Value = 'Please correct curves at all phases by dragging control points.';                    
                        end
                    end
                else % manual correction
                    xdata = [app.Points{1,currentTimeStep}(:,1); app.Points{2,currentTimeStep}(:,1)];
                    ydata = [app.Points{1,currentTimeStep}(:,2); app.Points{2,currentTimeStep}(:,2)];
                    if 5 > min(sqrt((xdata-app.ContourView.CurrentPoint(1,1)).^2+(ydata-app.ContourView.CurrentPoint(1,2)).^2),[],1)
                        [~,app.pointToMove] = min(sqrt((xdata-app.ContourView.CurrentPoint(1,1)).^2+(ydata-app.ContourView.CurrentPoint(1,2)).^2),[],1);
                        set(app.SeptalCurvatureToolUIFigure,...
                            'WindowButtonMotionFcn',  {@DragMouse_Callback,'move'},...
                            'WindowButtonUpFcn',      {@DragMouse_Callback,'up'});
                    end

                end
            end
            
            function DragMouse_Callback(hobj,~,action)
                pos = get(app.ContourView,'CurrentPoint');
                switch action
                    case 'move'
                        if app.pointToMove<=3
                            app.Points{1,currentTimeStep}(app.pointToMove  ,1) = pos(1);
                            app.Points{1,currentTimeStep}(app.pointToMove  ,2) = pos(3);
                        else
                            app.Points{2,currentTimeStep}(app.pointToMove-3,1) = pos(1);
                            app.Points{2,currentTimeStep}(app.pointToMove-3,2) = pos(3);
                        end
                        drawManualCurves(app,currentTimeStep);
                    case 'up'
                        set(app.SeptalCurvatureToolUIFigure,...
                            'WindowButtonMotionFcn',  '',...
                            'WindowButtonUpFcn',      '');
                        
                        [  ~,  ~, rinv ] = CircleFitting(app,app.Points{1,currentTimeStep}(:,1),app.Points{1,currentTimeStep}(:,2));
                        app.CurvatureFreeWall(currentTimeStep,2) = rinv;
                        [ cx, cy, rinv ] = CircleFitting(app,app.Points{2,currentTimeStep}(:,1),app.Points{2,currentTimeStep}(:,2));
                        sept_to_freewall = mean(app.Points{1,currentTimeStep},1)-mean(app.Points{2,currentTimeStep},1);
                        sept_to_center   = [cx cy] - mean(app.Points{2,currentTimeStep},1);
                        if dot(sept_to_center,sept_to_freewall) > 0
                            app.CurvatureSeptum(currentTimeStep,2)   = rinv;
                        else
                            app.CurvatureSeptum(currentTimeStep,2)   = -rinv;
                        end
                        updateCurvaturePlot(app,app.CurvatureSeptum(:,2),app.CurvatureFreeWall(:,2));
                end
            end


        end

        % Button pushed function: ResetmanualpointsButton
        function ResetmanualpointsButtonPushed(app, event)
            if strcmpi(app.PointsforcirclefittingDropDown.Value,'Manual')
                app.TimeStepSpinner.Value = 1;
                currentTimeStep = app.TimeStepSpinner.Value;
                app.registrationDone = false;
                hold(app.ContourView, 'off');
                hdl = imshow(app.SAXimg{currentTimeStep},[0 512],'Parent',app.ContourView, ...
                    'Xdata',[1 size(app.SAXimg{currentTimeStep},2)*app.PixelSpacing(1)], ...
                    'Ydata',[1 size(app.SAXimg{currentTimeStep},1)*app.PixelSpacing(2)]);
                set(hdl,'HitTest','off');
                hold(app.ContourView, 'on');
                % reset plot panes
                reset_plotviews(app,app.CurvatureView);
                reset_plotviews(app,app.CurvatureRatioView);
                reset_plotviews(app,app.CurvatureAreaView);
                app.Points = [];
                app.messagetxt.Value = 'Place 3 points at the LV free wall';
                set(app.TimeStepSpinner,'Enable','off');
            end
        end

        % Button pushed function: SavemanualpointsButton
        function SavemanualpointsButtonPushed(app, event)
            saveManualpoints(app);
        end

        % Button pushed function: LoadmanualpointsButton
        function LoadmanualpointsButtonPushed(app, event)
            loadManualpoints_computeCurvature(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SeptalCurvatureToolUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {738, 738, 738};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {738, 738};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 379};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SeptalCurvatureToolUIFigure = uifigure('Visible', 'off');
            app.SeptalCurvatureToolUIFigure.Name = 'Septal Curvature Tool - (c)  University of Colorado Anschutz Medical Campus';
            app.SeptalCurvatureToolUIFigure.Icon = fullfile(pathToMLAPP, 'uiicon.png');
            app.SeptalCurvatureToolUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SeptalCurvatureToolUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadContourButton = uibutton(app.LeftPanel, 'push');
            app.LoadContourButton.ButtonPushedFcn = createCallbackFcn(app, @LoadContourButtonPushed, true);
            app.LoadContourButton.Text = 'Load Contour';
            app.TimeStepSpinnerLabel = uilabel(app.LeftPanel);
            app.TimeStepSpinnerLabel.Text = 'Time Step';
            app.TimeStepSpinner = uispinner(app.LeftPanel);
            app.TimeStepSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeStepSpinnerValueChanged, true);
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ReferencepointsDropDownLabel = uilabel(app.LeftPanel);
            app.ReferencepointsDropDownLabel.Text = 'Reference points';
            app.ReferencepointsDropDown = uidropdown(app.LeftPanel);
            app.ReferencepointsDropDown.Items = {'From file', 'Automatic'};
            app.ReferencepointsDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferencepointsDropDownValueChanged, true);
            app.ReferencepointsDropDown.Value = 'From file';
            app.CurvatureareaDropDownLabel = uilabel(app.LeftPanel);
            app.CurvatureareaDropDownLabel.Text = 'Curvature area ';
            app.CurvatureareaDropDown = uidropdown(app.LeftPanel);
            app.CurvatureareaDropDown.Items = {'Line-based', 'Curve-based'};
            app.CurvatureareaDropDown.ValueChangedFcn = createCallbackFcn(app, @CurvatureareaDropDownValueChanged, true);
            app.CurvatureareaDropDown.Value = 'Line-based';
            app.BatchanalysisButton = uibutton(app.LeftPanel, 'push');
            app.BatchanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @BatchanalysisButtonPushed, true);
            app.BatchanalysisButton.Text = 'Batch analysis';
            app.PointsforcirclefittingLabel = uilabel(app.LeftPanel);
            app.PointsforcirclefittingLabel.Text = {'Points for '; 'circle fitting'};
            app.PointsforcirclefittingDropDown = uidropdown(app.LeftPanel);
            app.PointsforcirclefittingDropDown.Items = {'Auto', 'Manual'};
            app.PointsforcirclefittingDropDown.ValueChangedFcn = createCallbackFcn(app, @PointsforcirclefittingDropDownValueChanged, true);
            app.PointsforcirclefittingDropDown.Value = 'Auto';
            app.SavemanualpointsButton = uibutton(app.LeftPanel, 'push');
            app.SavemanualpointsButton.ButtonPushedFcn = createCallbackFcn(app, @SavemanualpointsButtonPushed, true);
            app.SavemanualpointsButton.Text = 'Save manual points';
            app.LoadmanualpointsButton = uibutton(app.LeftPanel, 'push');
            app.LoadmanualpointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmanualpointsButtonPushed, true);
            app.LoadmanualpointsButton.Text = 'Load manual points';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ContourView = uiaxes(app.CenterPanel);
            title(app.ContourView, 'Contour View')
            xlabel(app.ContourView, 'X [mm]')
            ylabel(app.ContourView, 'Y [mm]')
            app.ContourView.ButtonDownFcn = createCallbackFcn(app, @ContourViewButtonDown, true);
            app.messagetxt = uitextarea(app.CenterPanel);
            app.ResetmanualpointsButton = uibutton(app.CenterPanel, 'push');
            app.ResetmanualpointsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetmanualpointsButtonPushed, true);
            app.ResetmanualpointsButton.Text = 'Reset manual points ';
            app.RightPanel = uipanel(app.GridLayout);
            app.CurvatureView = uiaxes(app.RightPanel);
            title(app.CurvatureView, 'Curvature')
            xlabel(app.CurvatureView, 'Time Step')
            ylabel(app.CurvatureView, 'Curvature [1/mm]')
            app.CurvatureRatioView = uiaxes(app.RightPanel);
            title(app.CurvatureRatioView, 'Normalized Curvature [Septal/FreeWall]')
            xlabel(app.CurvatureRatioView, 'Time Step')
            ylabel(app.CurvatureRatioView, 'Normalized Curvature [ - ]')
            zlabel(app.CurvatureRatioView, 'Z')
            app.CurvatureAreaView = uiaxes(app.RightPanel);
            title(app.CurvatureAreaView, 'Curvature area')
            xlabel(app.CurvatureAreaView, 'Time Step')
            ylabel(app.CurvatureAreaView, 'Curvature area [mm^2]')
            zlabel(app.CurvatureAreaView, 'Z')
            % Show the figure after all components are created
            app.SeptalCurvatureToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = curvatureTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SeptalCurvatureToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SeptalCurvatureToolUIFigure)
        end
    end
end